classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExitButton                  matlab.ui.control.Button
        SelectButton                matlab.ui.control.Button
        ApplicationforapplyingfiltersonanimageLabel  matlab.ui.control.Label
        ChooseafilterDropDown       matlab.ui.control.DropDown
        ChooseafilterDropDownLabel  matlab.ui.control.Label
        ApplyButton                 matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        InputImg % Description
        OutputImg % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function select(app, event)
            [file,path]=uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.InputImg = imread(fullfile(path,file));
            imshow(app.InputImg,'Parent',app.UIAxes2);
        end

        % Callback function
        function Sobel(app, event)

            
        end

        % Callback function
        function S(app, event)
            
        end

        % Callback function
        function ApplyButtonPushed(app, event)
            
        end

        % Value changed function: ChooseafilterDropDown
        function ChooseafilterDropDownValueChanged(app, event)
            value = app.ChooseafilterDropDown.Value;
            switch value
                case 'Funny'
                    image1=app.InputImg;
                    A=rgb2gray(image1);
                    [row,col]=size(A);
                    for i=1:1:row
                        for j=1:1:col
                            if(A(i,j)>=0) && (A(i,j)<90)
                                red(i,j)=0;
                                green(i,j)=0;
                                blue(i,j)=0;
                            elseif(A(i,j)>=90) && (A(i,j)<180)
                                red(i,j)=168;
                                green(i,j)=222;
                                blue(i,j)=190;
                            elseif(A(i,j)>=180) && (A(i,j)<144)
                                red(i,j)=255;
                                green(i,j)=222;
                                blue(i,j)=190;
                            elseif(A(i,j)>=144) && (A(i,j)<198)
                                red(i,j)=255;
                                green(i,j)=245;
                                blue(i,j)=255;
                            elseif(A(i,j)>=198) && (A(i,j)<255)
                                red(i,j)=255;
                                green(i,j)=255;
                                blue(i,j)=255;
                            end
                        end
                    end
                    app.OutputImg=cat(3,red,green,blue);
                    app.OutputImg=app.OutputImg/255;
                case 'Increased color'
                    X=app.InputImg;
                    X=im2double(X);
                    rc=X(:,:,1);
                    gc=X(:,:,2);
                    bc=X(:,:,3);
                    Amp_factor=1.33;
                    rc=(rc*Amp_factor);
                    app.OutputImg=cat(3,rc,gc,bc);
                case 'Logarithm filter'
                    Y=app.InputImg;
                    Y=im2double(Y);
                    c=5;
                    app.OutputImg=c.*log(0.7+Y);
                case 'Dehazing filter'
                    E=app.InputImg;
                    E2=rgb2lab(E);
                    e=imcomplement(E2(:,:,1)./100);
                    lenh=imcomplement(imreducehaze(e,'ContrastEnhancement','none'));
                    enh(:,:,1)=lenh.*100;
                    enh(:,:,2:3)=E2(:,:,2:3)*2;
                    app.OutputImg=lab2rgb(enh);
                case 'illumination'
                    I=app.InputImg;
                    I2=imcomplement(I);
                    [Inv,Cnv]=imreducehaze(I2,'Method','approxdcp','ContrastEnhancement','none');
                    i=imcomplement(Cnv);
                    app.OutputImg=i;
                    
                case 'Dark'
                    D=app.InputImg;
                    D2=rgb2lab(D);
                    max_d=100;
                    d=D2(:,:,1)/max_d;
                    result=D2;
                    result(:,:,1)=histeq(d)*max_d;
                    app.OutputImg=lab2rgb(result);
                case 'Flutter'
                    K=app.InputImg;
                    K2=rgb2lab(K);
                    max_f=100;
                    k=K2(:,:,1)/max_f;
                    output=K2;
                    output(:,:,1)=adapthisteq(k)*max_f;
                    app.OutputImg=lab2rgb(output);
                case 'Bright'
                    image=app.InputImg;
                    H=2.5;
                    Himage=rgb2hsv(image);
                    Himage(:,:,1)=mod(Himage(:,:,1)*H,1);
                    app.OutputImg=hsv2rgb(Himage);
                case 'Winter'
                    im=app.InputImg;
                    h=2.5;
                    Wim=rgb2hsv(im);
                    Wim(:,:,1)=mod(Wim(:,:,1)+h,1);
                    app.OutputImg=hsv2rgb(Wim);
                case 'Red'
                    R=app.InputImg;
                    R(:,:,2:3)=0;
                    app.OutputImg=R;
                case 'Green'
                    G=app.InputImg;
                    G(:,:,1:2:3)=0
                    app.OutputImg=G;
                case 'Blue'
                    B=app.InputImg;
                    B(:,:,1:2)=0;
                    app.OutputImg=B;
                case 'Magenta'
                    b=app.InputImg;
                    b(:,:,1:2)=0;
                    r=app.InputImg;
                    r(:,:,2:3)=0;
                    Mag=b+r;
                    app.OutputImg=Mag;
                case 'Cyan'
                    b1=app.InputImg;
                    b1(:,:,1:2)=0;
                    g=app.InputImg;
                    g(:,:,1:2:3);
                    Cyan=b1+g;
                    app.OutputImg=Cyan;
                case 'Light'
                    A=app.InputImg;
                    app.OutputImg=imadd(A,80);
                
                
                case 'Slow light'
                    Z=app.InputImg;
                    Z1=imcomplement(Z);
                    z=imreducehaze(Z1);
                    app.OutputImg=imcomplement(z);

               
                case 'Gray showing'
                    
                    Gray=rgb2gray(app.InputImg);
                    app.OutputImg=Gray;
                case 'Canny filter'
                    
                    GRAY = rgb2gray(app.InputImg);
                    GRAY = double(GRAY);
                    %Value for Thresholding
                      T1 = 0.075;
                      T2 = 0.175;
                    %Now, we calculateGaussian Filter Coefficient
                       C = [2, 4, 5, 4, 2; 4, 9, 12, 9, 4;5, 12, 15, 12, 5;4, 9, 12, 9, 4;2, 4, 5, 4, 2 ];
                       C = 1/159.* C;
                    %Convolution of image using Gaussian Coefficient
                       Conv=conv2(GRAY, C, 'same');
                    %Filter for horizontal and vertical direction
                       Gx = [-1, 0, 1; -2, 0, 2; -1, 0, 1];
                       Gy = [1, 2, 1; 0, 0, 0; -1, -2, -1];  
                       Filt_X = conv2(Conv, Gx, 'same');
                       Filt_Y = conv2(Conv, Gy, 'same');
                    %Calculate directions/orientations
                       h1 = atan2 (Filt_Y, Filt_X);
                       h1 = h1*180/pi;
                    p=size(Conv,1);
                    l=size(Conv,2);
                    % making all directions positive
                      for i=1:p
                         for j=1:l
                          if (h1(i,j)<0) 
                          h1(i,j)=360+h1(i,j);
                          end
                         end
                      end
                    h2=zeros(p, l);
                    %Adjusting directions to nearest 0, 45, 90, or 135 degree
                      for i = 1 : p
                        for j = 1 : l
                          if ((h1(i, j) >= 0 ) && (h1(i, j) < 22.5) || (h1(i, j) >= 157.5) && (h1(i, j) < 202.5) || (h1(i, j) >= 337.5) && (h1(i, j) <= 360))
                           h2(i, j) = 0;
                          elseif ((h1(i, j) >= 22.5) && (h1(i, j) < 67.5) || (h1(i, j) >= 202.5) && (h1(i, j) < 247.5))
                           h2(i, j) = 45;
                          elseif ((h1(i, j) >= 67.5 && h1(i, j) < 112.5) || (h1(i, j) >= 247.5 && h1(i, j) < 292.5))
                           h2(i, j) = 90;
                          elseif ((h1(i, j) >= 112.5 && h1(i, j) < 157.5) || (h1(i, j) >= 292.5 && h1(i, j) < 337.5))
                           h2(i, j) = 135;
                          end
                       end
                      end
                     %Calculate magnitude
                       mag = (Filt_X.^2) + (Filt_Y.^2);
                       mag2 = sqrt(mag);
                       W = zeros (p, l);
                     %Non-Maximum Supression
                       for i=2:p-1
                         for j=2:l-1
                           if (h2(i,j)==0)
                            W(i,j) = (mag2(i,j) == max([mag2(i,j), mag2(i,j+1), mag2(i,j-1)]));
                           elseif (h2(i,j)==45)
                            W(i,j) = (mag2(i,j) == max([mag2(i,j), mag2(i+1,j-1), mag2(i-1,j+1)]));
                          elseif (h2(i,j)==90)
                            W(i,j) = (mag2(i,j) == max([mag2(i,j), mag2(i+1,j), mag2(i-1,j)]));
                          elseif (h2(i,j)==135)
                            W(i,j) = (mag2(i,j) == max([mag2(i,j), mag2(i+1,j+1), mag2(i-1,j-1)]));
                           end
                         end
                       end
                     W = W.*mag2;
                     %Hysteresis Thresholding
                      T1 = T1 * max(max(W));
                      T2 = T2 * max(max(W));
                    res = zeros (p, l);
                     for i = 1  : p
                       for j = 1 : l
                         if (W(i, j) < T1)
                           res(i, j) = 0;
                         elseif (W(i, j) > T2)
                           res(i, j) = 1;
                       %Using 8-connected components
                         elseif ( W(i+1,j)>T2 || W(i-1,j)>T2 || W(i,j+1)>T2 || W(i,j-1)>T2 || W(i-1, j-1)>T2 || W(i-1, j+1)>T2 || W(i+1, j+1)>T2 || W(i+1, j-1)>T2)
                           res(i,j) = 1;
                         end
                       end
                     end
                  app.OutputImg=uint8(res.*255);
                case 'Strong light'
                    Simg=app.InputImg;
                    app.OutputImg=imadjust(Simg,[.2 .3 0;.6 .7 1],[]);
                case 'Dark Summer'
                    inimg=app.InputImg;
                    in2=rgb2hsv(inimg);
                    in2(:,:,3)=histeq(in2(:,:,3));
                    app.OutputImg=hsv2rgb(in2);
                    

            end
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed2(app, event)
            imshow(app.OutputImg,'Parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Output')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Selected Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed2, true);
            app.ApplyButton.Text = 'Apply';
            app.ChooseafilterDropDownLabel = uilabel(app.UIFigure);
            app.ChooseafilterDropDownLabel.Text = 'Choose a filter';
            app.ChooseafilterDropDown = uidropdown(app.UIFigure);
            app.ChooseafilterDropDown.Items = {'Choose', 'Dehazing filter', 'Slow light', 'Increased color', 'Dark', 'Bright', 'Winter', 'Dark Summer', 'Logarithm filter', 'Flutter', 'Gray showing', 'Strong light', 'Red', 'Green', 'Blue', 'Magenta', 'Cyan', 'Light', 'Funny', 'illumination', 'Canny filter'};
            app.ChooseafilterDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseafilterDropDownValueChanged, true);
            app.ChooseafilterDropDown.Value = 'Choose';
            app.ApplicationforapplyingfiltersonanimageLabel = uilabel(app.UIFigure);
            app.ApplicationforapplyingfiltersonanimageLabel.Text = 'Application for applying filters on an image';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @select, true);
            app.SelectButton.Text = 'Select';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end